classdef CCT_App_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MeasureSpectrumButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        startupHelper 
        spectrometer
        cancellationToken
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Get the folder where the .mlapp file is located
            dll_path = fileparts(mfilename('fullpath'));
            
            NET.addAssembly(fullfile(dll_path, 'Thorlabs.ManagedDevice.CompactSpectrographDriver.dll'));  
            NET.addAssembly(fullfile(dll_path, 'Thorlabs.ManagedDevice.dll'));  
            NET.addAssembly(fullfile(dll_path, 'Microsoft.Extensions.Logging.Abstractions.dll'));  
            NET.addAssembly('System.Runtime');
            
            import Thorlabs.ManagedDevice.CompactSpectrographDriver.Workflow.StartupHelperCompactSpectrometer.*;
            import Thorlabs.ManagedDevice.Trace.ExampleLogger.*;
            import Microsoft.Extensions.Logging.Abstractions.*;
            import Thorlabs.ManagedDevice.CompactSpectrographDriver.ICompactSpectrographDriver.*;
            
            logger = Thorlabs.ManagedDevice.Trace.ExampleLogger('ML',Microsoft.Extensions.Logging.LogLevel.Trace,1==1,'MatLab');
            app.startupHelper = Thorlabs.ManagedDevice.CompactSpectrographDriver.Workflow.StartupHelperCompactSpectrometer(logger);
            
            cts = System.Threading.CancellationTokenSource();
            app.cancellationToken = cts.Token;
            
            %find devices
            discoveredDevicestask=app.startupHelper.GetKnownDevicesAsync(app.cancellationToken);
            discoveredDevicestask.Wait();
            discoveredDevices=discoveredDevicestask.Result;
            
            for i=0:discoveredDevices.Count-1
                disp("discovered devices:")
                disp(discoveredDevices.Item(i));
            end
            
            %if spectrometers are found
            if(discoveredDevices.Count>0)
            
                disp("connecting to first device ...")
            
                Deviceid=discoveredDevices.Item(0);
                
                app.spectrometer=app.startupHelper.GetCompactSpectrographById(Deviceid);
            
                %set exposure time
                exposure=500; %exposure time in milliseconds
                exposure_result=app.spectrometer.SetManualExposureAsync(exposure,app.cancellationToken).Result;
                if exposure_result==1
                    disp(['exposure time is set to ',num2str(exposure),' ms']);
                end


            else
                disp("No CCT spectrometer connected")
                app.startupHelper.Dispose();
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.startupHelper.Dispose();
            delete(app)
            
        end

        % Button pushed function: MeasureSpectrumButton
        function MeasureSpectrumButtonPushed(app, event)
            %acquire spectrum
            spectrumtask=app.spectrometer.AcquireSingleSpectrumAsync(app.cancellationToken);
            spectrumtask.Wait();
            spectrum=spectrumtask.Result;
        
            %dispose startup helper
            %startupHelper.Dispose();
        
            %plot spectrum
            %figure; 
            plot(app.UIAxes,spectrum.Wavelength,spectrum.Intensity)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CCT Spectrum')
            xlabel(app.UIAxes, 'Wavelength [nm]')
            zlabel(app.UIAxes, 'Z')
            app.MeasureSpectrumButton = uibutton(app.UIFigure, 'push');
            app.MeasureSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureSpectrumButtonPushed, true);
            app.MeasureSpectrumButton.Text = 'Measure Spectrum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCT_App_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end