classdef PowermeterAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PowerWEditField       matlab.ui.control.NumericEditField
        PowerWEditFieldLabel  matlab.ui.control.Label
        MeasureButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        pm % powermeter device handle
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %This app was tested with OPM 7.0, PM100D3
            
            NET.addAssembly('C:\Program Files (x86)\Microsoft.NET\Primary Interop Assemblies\Thorlabs.TLPMX_64.Interop.dll');

            import Thorlabs.TLPMX_64.Interop.*;
            
            %Create a dummy TLPMX object to check for compatible devices.
            handle = System.IntPtr(0);
            device = TLPMX(handle);
            %Look for connected devices
            [~,deviceCount] = device.findRsrc();
            
            if deviceCount == 0
                disp( 'Unable to find compatible connected devices. Is the device connected, on, and using the TLPMX driver? This example will not work with the legacy IVI drivers.');
                delete(app);                     
            elseif deviceCount >= 1
                deviceName=System.Text.StringBuilder(256);
                device.getRsrcName(0, deviceName);
                app.pm = TLPMX(deviceName.ToString(), true, false);
            
                disp('Device connected:');
                disp(deviceName.ToString());
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
             [~, power]=app.pm.measPower(0);
             app.PowerWEditField.Value=power;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest2(app, event)
            if ~isempty(app.pm)
                    app.pm.Dispose(); 
                    app.pm=[];
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest2, true);
            app.MeasureButton = uibutton(app.UIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.PowerWEditFieldLabel = uilabel(app.UIFigure);
            app.PowerWEditFieldLabel.Text = 'Power [W]';
            app.PowerWEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowermeterAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end